classdef ROImaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ManageFilesTab                  matlab.ui.container.Tab
        SetsavedirectoryButton          matlab.ui.control.Button
        NosavedirectoryLabel            matlab.ui.control.Label
        RemoveselectedROIsetButton      matlab.ui.control.Button
        RemoveselectedimageButton       matlab.ui.control.Button
        LoadROIsetButton                matlab.ui.control.Button
        LoadimageimageseriesButton      matlab.ui.control.Button
        UITable_RoiSet                  matlab.ui.control.Table
        UITable_Image                   matlab.ui.control.Table
        AutoROIsTab                     matlab.ui.container.Tab
        autodetectROIsButton            matlab.ui.control.Button
        framenumbersPanel               matlab.ui.container.Panel
        keyboardshortcutctrlnumLabel    matlab.ui.control.Label
        includedButton                  matlab.ui.control.Button
        uncertaintyButton               matlab.ui.control.Button
        edgeButton                      matlab.ui.control.Button
        enclosedButton                  matlab.ui.control.Button
        circularityButton               matlab.ui.control.Button
        brightnessButton                matlab.ui.control.Button
        diameterButton                  matlab.ui.control.Button
        ROIsButton_3                    matlab.ui.control.Button
        ROIsButton_2                    matlab.ui.control.Button
        meanButton_2                    matlab.ui.control.Button
        SaveROIsetPanel                 matlab.ui.container.Panel
        ROIsetnameEditField             matlab.ui.control.EditField
        ROIsetnameEditFieldLabel        matlab.ui.control.Label
        storeROIsetButton               matlab.ui.control.Button
        RefineROIsetPanel               matlab.ui.container.Panel
        diameterLabel                   matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        InclusionCriteriaLabel          matlab.ui.control.Label
        UITable_RoiSet_2                matlab.ui.control.Table
        ImageFilenameDropDown           matlab.ui.control.DropDown
        ImageFilenameDropDownLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ManualROIsTab                   matlab.ui.container.Tab
        SavechangesButton               matlab.ui.control.Button
        createnewROIsetButton           matlab.ui.control.Button
        BeginmanualeditButton           matlab.ui.control.Button
        frameshortcutsPanel             matlab.ui.container.Panel
        keyboardshortcutctrlnumLabel_2  matlab.ui.control.Label
        ofroisonoffButton               matlab.ui.control.StateButton
        maxButton                       matlab.ui.control.Button
        meanButton                      matlab.ui.control.Button
        EditROIPanel                    matlab.ui.container.Panel
        aaddROIButton                   matlab.ui.control.Button
        ddeleteROIButton                matlab.ui.control.Button
        selectedROIDropDown             matlab.ui.control.DropDown
        selectedROIDropDownLabel        matlab.ui.control.Label
        UITable_RoiSet_3                matlab.ui.control.Table
        ROIsetnameDropDown              matlab.ui.control.DropDown
        ROIsetnameDropDownLabel         matlab.ui.control.Label
        ImageFilenameDropDown_2         matlab.ui.control.DropDown
        ImageFilenameDropDown_2Label    matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        CompareSetsTab                  matlab.ui.container.Tab
        SelectROIsetsPanel_2            matlab.ui.container.Panel
        UITable2_2                      matlab.ui.control.Table
        UITable_RoiSetMask_2            matlab.ui.control.Table
        UITable_RoiSet_6                matlab.ui.control.Table
        UIAxes_5                        matlab.ui.control.UIAxes
        CreateMasksTab                  matlab.ui.container.Tab
        CreatemasksPanel                matlab.ui.container.Panel
        createmasksButton               matlab.ui.control.Button
        roineuropilspacingEditField     matlab.ui.control.NumericEditField
        roineuropilspacingEditFieldLabel  matlab.ui.control.Label
        minneuropilpixelsEditField      matlab.ui.control.NumericEditField
        minneuropilpixelsEditFieldLabel  matlab.ui.control.Label
        SelectROIsetsPanel              matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        UITable_RoiSetMask              matlab.ui.control.Table
        UITable_RoiSet_4                matlab.ui.control.Table
        UIAxes_3                        matlab.ui.control.UIAxes
        ProcessandSaveTab               matlab.ui.container.Tab
        showrawneuCheckBox              matlab.ui.control.CheckBox
        ROIDropDown                     matlab.ui.control.DropDown
        ROIDropDownLabel                matlab.ui.control.Label
        GeneratetracesPanel             matlab.ui.container.Panel
        RemoveselectedtraceButton       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SavetracesButton                matlab.ui.control.Button
        CalculatetracesButton           matlab.ui.control.Button
        SaveallROIsetsButton            matlab.ui.control.Button
        UITable_RoiSet_5                matlab.ui.control.Table
        ROIsetnameDropDown_2            matlab.ui.control.DropDown
        ROIsetnameDropDown_2Label       matlab.ui.control.Label
        ImageFilenameDropDown_3         matlab.ui.control.DropDown
        ImageFilenameDropDown_3Label    matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %loaded images
        images % cell array of loaded images
        imageFilenames % cell array of image filenames (1 for each image)
        imageFilepaths % cell array of image filepaths (1 for each image)
        imageHeights % array of frame heights (1 for each loaded image)
        imageWidths % array of frame widths (1 for each loaded image)
        numFrames % array of frame numbers (1 for each loaded image)
        numImages % number of loaded images
        selectedImageRows % user-selected image rows
        
        %loaded ROI sets
        autoRois % struct of currently selected automatically generated ROI set
        manualRois % struct of currently selected manually editable ROI set
        roiSets % cell array of ROI sets
        roiSetFilenames % cell array of filenames (1 for for ROI set)
        roiSetFilepaths % cell array of filepaths (1 for for ROI set)
        originImages % cell array of imageFilenames
        roiOriginImage % imageFilename of current ROI set
        roiMethod % method used for roi detection (e.g. 'auto', 'manual')
        roiSetNames % cell array of custom names (1 for each ROI set)
        numRois % array of number of ROIs (1 value for each ROI set)
        numRoiSets % number of loaded ROI sets
        selectedRoiSetRows % user-selected ROI set rows
        
        %auto ROI stuff
        curImageNum % index of the currently selected image
        curRoiSetNum % index of the currently selected ROI set
        selectedAutoFrame % the currently selected image frame in the auto ROI tab
        selectedCriteria % index of the currently selected criteria/parameter
        minCriteria % minimum parameter values to include ROIs
        maxCriteria % maximum parameter values to include ROIs
        includedRois % list of rois in current set that meets all inclusion criteria
        rtextHandles % list of handles for autodetected ROI text
        rlineHandles % list of handles for autodetected ROI lines
        blpatchHandle % list of handles for autodetected ROI black patch
            
        %manual ROI stuff
        selectedManualFrame % the currently selected image frame in the manual ROI tab
        selectedRoiOnOff % whether to display ROIs
        selectedManualRoi % currently selected ROI #
        mlineHandles % list of handles for manual ROI lines
        plHandle % handle for current editable polyline
        meanImageHandle % handle for mean projection image
        maxImageHandle % handle for max projection image
        
        %mask stuff
        selectedRoiSetMasks % list of ROI sets selected for mask creation
        
        %traces stuff
        curTraceNum % # of the currently selected traces data
        numTraces % number of calculated fluorescence traces
        traces % struct of calculated traces (F, Fneu, Fraw, ImageFilename, RoiSetname)
        tracesFilenames % cell array of filenames
        tracesFilepaths % cell array of filepaths
        selectedTraceRows % list of traces selected
        selectedTraceRoi % selected ROI to visualize trace
        showTraceRaw % whether to plot raw/neuropil data
        fHandle % handle for current fluorescence trace
        frawHandle % handle for current raw fluorescence trace
        fneuHandle % handle for current neuropil fluorescence trace
        
        %misc
        saveDirectory % folder to save results
        ctrlHeld % bool of whether the control button is being held down
        
    end
    
    methods (Access = private)
        
        function updateLists(app)
            %update Image table
            data = cell(app.numImages,5);
            data(:,1) = app.imageFilepaths;
            data(:,2) = app.imageFilenames;
            for i = 1:app.numImages
                data(i,3) = {app.imageHeights(i)};
                data(i,4) = {app.imageWidths(i)};
                data(i,5) = {app.numFrames(i)};
            end
            set(app.UITable_Image,'Data',data);
            
            %update ROI set tables
            data = cell(app.numRoiSets,8);
            data(:,1) = app.roiSetFilepaths;
            data(:,2) = app.roiSetFilenames;
            data(:,6) = app.roiMethod;
            for i = 1:app.numRoiSets
                data(i,3) = app.roiSetNames(i); %roiSetName
                data(i,4) = app.originImages(i); %image that the rois were generated from
                data(i,5) = {app.numRois(i)}; %numRois
                if isempty(app.roiSets{i,5})
                    data{i,7} = 'no'; %maskscreated
                else
                    data{i,7} = 'yes';
                end
                data(i,8) = app.roiSets(i,7); %neuRoiSets
            end
            set(app.UITable_RoiSet,'Data',data);
            set(app.UITable_RoiSet_2,'Data',data);
            set(app.UITable_RoiSet_3,'Data',data);
            set(app.UITable_RoiSet_4,'Data',data);
            set(app.UITable_RoiSet_5,'Data',data);
            
            %update ROI set mask table
            data = cell(app.numRoiSets,4);
            colors = linspecer(app.numRoiSets);
            for i = 1:app.numRoiSets
                data(i,1) = app.roiSetNames(i);
                data(i,2) = {colors(i,1)};
                data(i,3) = {colors(i,2)};
                data(i,4) = {colors(i,3)};
            end
            set(app.UITable_RoiSetMask,'Data',data);
            
            %update name Dropdowns
            app.ImageFilenameDropDown.Items = app.imageFilenames;
            app.ImageFilenameDropDown_2.Items = app.imageFilenames;
            app.ImageFilenameDropDown_3.Items = app.imageFilenames;
            app.ROIsetnameDropDown.Items = app.roiSetNames;
            app.ROIsetnameDropDown_2.Items = app.roiSetNames;
            
            %update trace table
            data = cell(app.numTraces,2);
            for i = 1:app.numTraces
                if ~isempty(app.tracesFilepaths(i))
                    data(i,1) = app.tracesFilepaths(i);
                end
                data(i,2) = app.tracesFilenames(i);
                data(i,3) = {app.traces(i).numFrames};
                data(i,4) = {app.traces(i).numRois};
            end
            set(app.UITable,'Data',data);
            
            %update roi dropdown
            if app.numTraces>0
                nums = (1:app.traces(app.curTraceNum).numRois)';
                app.ROIDropDown.Items = cellstr(num2str(nums,'%03d'));
            end
        end
        
        function val = makeCell(~,val)
            if ~iscell(val)
                val = {val};
            end
        end
        
        function updateAutoFrame(app)
            if ~isempty(app.autoRois)
                numEllipses = size(app.autoRois.center,1);
                app.includedRois = app.getIncludedRois(1:6);
                if app.selectedAutoFrame>4
                    app.selectedCriteria = app.selectedAutoFrame-4;
                end
                visibilities = {'on','off'};
                colors = [0 1 0; 1 0 1];
                
                %change visibility of mean image
                if any(app.selectedAutoFrame==[1 2])
                    app.blpatchHandle.FaceAlpha = 0;
                else
                    app.blpatchHandle.FaceAlpha = 1;
                end
                
                %change color/visibility of vertices
                if app.selectedAutoFrame==1 %only draw included rois
                    includedVis = app.getIncludedRois(1:6);
                    includedColor = app.getIncludedRois(1:6);
                elseif app.selectedAutoFrame==3 %draw included + excluded in different colors
                    includedVis = ones(1,numEllipses);
                    includedColor = app.getIncludedRois(1:6);
                else %dont draw any rois
                    includedVis = zeros(1,numEllipses);
                    includedColor = app.getIncludedRois(1:6);
                end
                for r = 1:numEllipses
                    app.rlineHandles(r).Color = colors(2-includedColor(r),:);
                    app.rlineHandles(r).Visible = visibilities{2-includedVis(r)};
                end
                
                %write text in axis
                if app.selectedAutoFrame<4 % no text
                    textVal = 1:size(app.autoRois.center,1);
                    includedVis = zeros(1,numEllipses);
                    includedColor = app.getIncludedRois(1:6);
                elseif app.selectedAutoFrame==4 % ROI #
                    textVal = 1:size(app.autoRois.center,1);
                    includedVis = ones(1,numEllipses);
                    includedColor = app.getIncludedRois(1:6);
                elseif app.selectedAutoFrame>4 %parameter for inclusion criteria
                    textVal = app.autoRois.parameters(:,app.selectedCriteria);
                    textVal = round(textVal*100)/100;
                    includedVis = ones(1,numEllipses);
                    includedColor = app.getIncludedRois(app.selectedCriteria);
                end
                for r = 1:numEllipses
                    app.rtextHandles(r).String = num2str(textVal(r));
                    app.rtextHandles(r).Color = colors(2-includedColor(r),:);
                    app.rtextHandles(r).Visible = visibilities{2-includedVis(r)};
                end
                
                %write axis title
                titleNames = {['1: mean image + included ROIs (' num2str(sum(app.includedRois)) '/' num2str(numEllipses) ')'],...
                    '2: mean image',...
                    '3: all ROIs',...
                    '4: ROI numbers',...
                    '5: ROI diameter',...
                    '6: ROI circularity',...
                    '7: ROI brightness',...
                    '8: distance to edge',...
                    '9: fraction of ROI enclosed',...
                    '10: uncertainty of ellipse fit'};
                app.UIAxes.Title.String = titleNames{app.selectedAutoFrame};
                
                %update criteria box
                app.diameterLabel.Text = titleNames{app.selectedCriteria+4};
                app.minEditField.Value = app.minCriteria(app.selectedCriteria);
                app.maxEditField.Value = app.maxCriteria(app.selectedCriteria);
            end
        end
        
        function included = getIncludedRois(app,cols)
            included = app.autoRois.parameters(:,cols)>=repmat(app.minCriteria(cols),[size(app.autoRois.center,1) 1]) ...
                     & app.autoRois.parameters(:,cols)<=repmat(app.maxCriteria(cols),[size(app.autoRois.center,1) 1]);
            included = all(included,2);
        end
        
        function updateManualFrame(app)
            if ~isempty(app.manualRois)
                %change visibility of images
                vis = {'off','off'};
                vis{app.selectedManualFrame} = 'on';
                app.meanImageHandle.Visible = vis{1};
                app.maxImageHandle.Visible = vis{2};
                
                %write axis title
                titleNames = {'1: mean image',...
                    '2: max projection image'};
                app.UIAxes_2.Title.String = titleNames{app.selectedManualFrame};
            end
        end
        
        function getManualLine(app)
            if ~isempty(app.plHandle) && isvalid(app.plHandle)
                %get position of polyline
                vertices = app.plHandle.Position;
                app.manualRois.vertices(app.selectedManualRoi) = {vertices};
                app.mlineHandles(app.selectedManualRoi).XData = vertices(:,1);
                app.mlineHandles(app.selectedManualRoi).YData = vertices(:,2);
                
                %calculate center
                center = mean(vertices);
                app.manualRois.center(app.selectedManualRoi,:) = center;
                                
                %get parameters of current image
                imageW = app.imageWidths(app.curImageNum);
                imageH = app.imageHeights(app.curImageNum);
                                
                %calculate radius
                x = vertices(:,1)-center(1);
                y = vertices(:,2)-center(2);
                radiuses = sqrt(x.^2 + y.^2);
                radius = mean(radiuses);
                
                %calculate circularity
                polyin = polyshape(vertices(:,1),vertices(:,2));
                roiArea = area(polyin);
                roiPerimeter = perimeter(polyin);
                circularity = roiPerimeter^2/(4*pi*roiArea);
                
                %calculate distance to edge
                xEdgeDist = min([center(1) imageW-center(1)]);
                yEdgeDist = min([center(2) imageH-center(2)]);
                distanceToEdge = min([xEdgeDist yEdgeDist]);
                
                %combine parameters into array
                app.manualRois.parameters(app.selectedManualRoi,:) = [radius circularity distanceToEdge];
            end
        end
        
        function updateTraceFrame(app)
            if ~isempty(app.selectedTraceRoi)
                
                if app.showTraceRaw
                    vis = 'on';
                    ylim(app.UIAxes_4,[0.9*min(app.traces(app.curTraceNum).F(:,app.selectedTraceRoi)) 1.1*max(app.traces(app.curTraceNum).Fraw(:,app.selectedTraceRoi))]);
                else
                    vis = 'off';
                    ylim(app.UIAxes_4,[0.9*min(app.traces(app.curTraceNum).F(:,app.selectedTraceRoi)) 1.1*max(app.traces(app.curTraceNum).F(:,app.selectedTraceRoi))]);
                end
                app.fHandle.YData = app.traces(app.curTraceNum).F(:,app.selectedTraceRoi);
                app.frawHandle.YData = app.traces(app.curTraceNum).Fraw(:,app.selectedTraceRoi);
                app.fneuHandle.YData = app.traces(app.curTraceNum).Fneu(:,app.selectedTraceRoi);
                app.frawHandle.Visible = vis;
                app.fneuHandle.Visible = vis;
                app.UIAxes_4.Title.String = ['ROI ' num2str(app.selectedTraceRoi) '/' num2str(app.traces(app.curTraceNum).numRois)];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numImages = 0;
            app.imageFilenames = {};
            app.imageFilepaths = {};
            app.imageHeights = [];
            app.imageWidths = [];
            app.numFrames = [];
            
            app.numRoiSets = 0;
            app.roiSetFilenames = {};
            app.roiSetFilepaths = {};
            app.roiSetNames = {};
            app.originImages = {};
            app.roiMethod = {};
            
            app.minCriteria = [7    1    50   10   0.8  0  ]; %[diameter, circularity, brightness, edge, enclosed, uncertainty]
            app.maxCriteria = [15   1.26 inf  inf  1    8  ]; 
            
            app.numTraces = 0;
            app.tracesFilenames = {};
            app.tracesFilepaths = {};
            
            app.selectedImageRows = [];
            app.selectedRoiSetRows = [];
            app.selectedAutoFrame = 1;
            app.selectedCriteria = 1;
            app.selectedManualFrame = 1;
            app.selectedRoiOnOff = 1;
            app.selectedManualRoi = [];
            app.ctrlHeld = false;
            app.saveDirectory = '';
        end

        % Button pushed function: LoadimageimageseriesButton
        function LoadimageimageseriesButtonPushed(app, event)
            [file, path] = uigetfile('*.tif','select tif image file','MultiSelect','on');
            file = app.makeCell(file);
            waitbarHandle = waitbar(0,'Loading image(s)');
            nf = length(file);
            for f = 1:nf
                curname = file{f};
                try %try loading the file, and if it loads, add it to the list
                    assert(exist('read_file','file')==2,'The function used to load .tif image files - read_file.m - is not on the Matlab path')
                    
                    curimage = read_file(fullfile(path,curname));
                    [curH, curW, curN] = size(curimage);
                    
                    app.numImages = app.numImages+1;
                    app.images{app.numImages} = curimage;
                    app.imageFilenames{app.numImages} = curname;
                    app.imageFilepaths{app.numImages} = path;
                    app.imageHeights(app.numImages) = curH;
                    app.imageWidths(app.numImages) = curW;
                    app.numFrames(app.numImages) = curN;
                    
                    if isempty(app.saveDirectory) && f==1
                        app.saveDirectory = path;
                        app.NosavedirectoryLabel.Text = path;
                    end
                catch ME
                    msgbox(['Couldn''t load image, error: ' ME.message]);
                end
                waitbar(f/nf,waitbarHandle);
                
                %update changes
                app.updateLists();
            end
            close(waitbarHandle)
            figure(app.UIFigure); %bring app to front
        end

        % Button pushed function: RemoveselectedimageButton
        function RemoveselectedimageButtonPushed(app, event)
            if ~isempty(app.selectedImageRows) && all(app.selectedImageRows<=app.numImages)
                app.numImages = app.numImages-length(app.selectedImageRows);
                app.images(app.selectedImageRows) = [];
                app.imageFilenames(app.selectedImageRows) = [];
                app.imageFilepaths(app.selectedImageRows) = [];
                app.imageHeights(app.selectedImageRows) = [];
                app.imageWidths(app.selectedImageRows) = [];
                app.numFrames(app.selectedImageRows) = [];
                app.updateLists();
            end
        end

        % Cell selection callback: UITable_Image
        function UITable_ImageCellSelection(app, event)
            rows = unique(event.Indices(:,1));
            app.selectedImageRows = rows;
        end

        % Button pushed function: SetsavedirectoryButton
        function SetsavedirectoryButtonPushed(app, event)
            app.saveDirectory = uigetdir('Select folder for saving data');
            app.NosavedirectoryLabel.Text = app.saveDirectory;
        end

        % Button pushed function: autodetectROIsButton
        function autodetectROIsButtonPushed(app, event)
            app.curImageNum = find(strcmp(app.ImageFilenameDropDown.Items,app.ImageFilenameDropDown.Value),1);
            app.roiOriginImage = fullfile(app.imageFilepaths{app.curImageNum},app.imageFilenames{app.curImageNum});
            app.selectedAutoFrame = 1;
            status = false;
            try
                app.autoRois = ellipse_finder(app.images{app.curImageNum});
                status = true;
            catch ME
                if ~strcmp(ME.message,'ROI diameter estimate is required')
                    error(ME.message)
                end
                figure(app.UIFigure);
            end
            
            if status
                cla(app.UIAxes);
                
                %create image
                meanImage = mean(double(app.images{app.curImageNum}),3);
                meanImage = meanImage/prctile(meanImage,99.5,'all');
                imshow(meanImage,'Parent',app.UIAxes);
                
                %create transparent patch over image (to block/unblock it)
                app.blpatchHandle = patch(app.UIAxes,[app.UIAxes.XLim fliplr(app.UIAxes.XLim) app.UIAxes.XLim(1)],...
                [app.UIAxes.YLim(1) app.UIAxes.YLim fliplr(app.UIAxes.YLim)],'k','EdgeColor','none','FaceAlpha',1);
                
                %set axis size/limits
                app.UIAxes.Position = [558,165,550,550];
                app.UIAxes.Title.String = 'mean';
                app.UIAxes.XLim = [0 app.imageWidths(app.curImageNum)];
                app.UIAxes.YLim = [0 app.imageHeights(app.curImageNum)];
                
                %draw vertices and text for each ROI
                numEllipses = size(app.autoRois.center,1);
                for r = 1:numEllipses
                    vertices = app.autoRois.vertices{r};
                    line(app.UIAxes,vertices(:,1),vertices(:,2),'Color',[0 0 0]);
                    %"-6" shift text over to the left a little bit
                    text(app.UIAxes,app.autoRois.center(r,1)-6,app.autoRois.center(r,2),'','Color',[0 0 0],'FontSize',8);
                end
                
                %get all line and text handles
                app.rtextHandles = findobj(app.UIAxes,'Type','text');
                app.rtextHandles = app.rtextHandles(numEllipses:-1:1); %reverse order to match ROI #s
                app.rlineHandles = findobj(app.UIAxes,'Type','line');
                app.rlineHandles = app.rlineHandles(numEllipses:-1:1);
                
                %update all visibilities
                app.updateAutoFrame();
            end
        end

        % Button pushed function: storeROIsetButton
        function storeROIsetButtonPushed(app, event)
            if ~isempty(app.autoRois)
                app.numRoiSets = app.numRoiSets + 1;
                if isempty(app.ROIsetnameEditField.Value) || strncmpi(app.ROIsetnameEditField.Value,'ROI set ',8)
                    app.ROIsetnameEditField.Value = ['ROI set ' num2str(app.numRoiSets)];
                end
                app.numRois(app.numRoiSets) = sum(app.includedRois);
                app.roiSets{app.numRoiSets,1} = app.autoRois.vertices(app.includedRois);
                app.roiSets{app.numRoiSets,2} = app.autoRois.center(find(app.includedRois),:);
                % parameters: exclude enclosed and uncertainty (auto-sepecific) and brightness (origin image specific) 
                app.roiSets{app.numRoiSets,3} = app.autoRois.parameters(find(app.includedRois),[1 2 4]);
                app.roiSets{app.numRoiSets,4} = [app.imageWidths(app.curImageNum) app.imageWidths(app.curImageNum)];
                app.roiSets{app.numRoiSets,5} = []; %space for masks
                app.roiSets{app.numRoiSets,6} = []; %space for neuMasks
                app.roiSets{app.numRoiSets,7} = []; %space for neuRoiSetNames
                app.roiSetFilenames{app.numRoiSets} =  [app.ROIsetnameEditField.Value '.mat'];
                app.roiSetFilepaths{app.numRoiSets} = app.saveDirectory;
                app.roiSetNames{app.numRoiSets} = app.ROIsetnameEditField.Value;
                app.originImages{app.numRoiSets} = app.roiOriginImage;
                app.roiMethod{app.numRoiSets} = 'auto';
                app.updateLists();
            end
            
        end

        % Button pushed function: includedButton
        function includedButtonPushed(app, event)
            app.selectedAutoFrame = 1;
            app.updateAutoFrame();
        end

        % Button pushed function: meanButton_2
        function meanButton_2Pushed(app, event)
            app.selectedAutoFrame = 2;
            app.updateAutoFrame();
        end

        % Button pushed function: ROIsButton_2
        function ROIsButton_2Pushed(app, event)
            app.selectedAutoFrame = 3;
            app.updateAutoFrame();
        end

        % Button pushed function: ROIsButton_3
        function ROIsButton_3Pushed(app, event)
            app.selectedAutoFrame = 4;
            app.updateAutoFrame();
        end

        % Button pushed function: diameterButton
        function diameterButtonPushed(app, event)
            app.selectedAutoFrame = 5;
            app.updateAutoFrame();
        end

        % Button pushed function: circularityButton
        function circularityButtonPushed(app, event)
            app.selectedAutoFrame = 6;
            app.updateAutoFrame();
        end

        % Button pushed function: brightnessButton
        function brightnessButtonPushed(app, event)
            app.selectedAutoFrame = 7;
            app.updateAutoFrame();
        end

        % Button pushed function: uncertaintyButton
        function uncertaintyButtonPushed(app, event)
            app.selectedAutoFrame = 10;
            app.updateAutoFrame();
        end

        % Button pushed function: enclosedButton
        function enclosedButtonPushed(app, event)
            app.selectedAutoFrame = 9;
            app.updateAutoFrame();
        end

        % Button pushed function: edgeButton
        function edgeButtonPushed(app, event)
            app.selectedAutoFrame = 8;
            app.updateAutoFrame();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'control')
                app.ctrlHeld = true;
            end
            if app.ctrlHeld
                
                switch app.TabGroup.SelectedTab.Title
                    case 'Manage Files'
                        
                    case 'Auto ROIs'
                        if any(strcmp(key,cellstr(num2str((0:9)'))))
                            keyNum = str2double(key);
                            app.selectedAutoFrame = keyNum;
                            if keyNum==0
                                app.selectedAutoFrame = 10;
                            end
                            app.updateAutoFrame();
                        end
                        
                    case 'Manual ROIs'
                        switch key
                            case 'a'
                                app.aaddROIButtonPushed();
                            case 'd'
                                app.ddeleteROIButtonPushed();
                            case 'o'
                                app.ofroisonoffButton.Value = true;
                                app.ofroisonoffButtonValueChanged();
                            case 'f'
                                app.ofroisonoffButton.Value = false;
                                app.ofroisonoffButtonValueChanged();
                            case '1'
                                
                            case '2'
                                
                        end
                        
                    case 'Create Masks'
                        
                    case 'Process and Save'
                        switch key
                            case 'uparrow'
                                app.selectedTraceRoi = min([app.traces(app.curTraceNum).numRois app.selectedTraceRoi+1]);
                                app.ROIDropDown.Value = num2str(app.selectedTraceRoi,'%03d');
                                app.updateTraceFrame();
                            case 'downarrow'
                                app.selectedTraceRoi = max([1 app.selectedTraceRoi-1]);
                                app.ROIDropDown.Value = num2str(app.selectedTraceRoi,'%03d');
                                app.updateTraceFrame();
                        end
                end
                
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if strcmp(key,'control')
                app.ctrlHeld = false;
            end
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            app.minCriteria(app.selectedCriteria) = app.minEditField.Value;
            app.updateAutoFrame();
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
            app.maxCriteria(app.selectedCriteria) = app.maxEditField.Value;
            app.updateAutoFrame();
        end

        % Button pushed function: RemoveselectedROIsetButton
        function RemoveselectedROIsetButtonPushed(app, event)
            if ~isempty(app.selectedRoiSetRows) && all(app.selectedRoiSetRows<=app.numRoiSets)
                app.numRoiSets = app.numRoiSets-length(app.selectedRoiSetRows);
                app.roiSetFilenames(app.selectedRoiSetRows) = [];
                app.roiSetFilepaths(app.selectedRoiSetRows) = [];
                app.roiSetNames(app.selectedRoiSetRows) = [];
                app.originImages(app.selectedRoiSetRows) = [];
                app.roiMethod(app.selectedRoiSetRows) = [];
                app.roiSets(app.selectedRoiSetRows,:) = [];
                app.numRois(app.selectedRoiSetRows) = [];
                app.updateLists();
            end
        end

        % Cell selection callback: UITable_RoiSet
        function UITable_RoiSetCellSelection(app, event)
            rows = unique(event.Indices(:,1));
            app.selectedRoiSetRows = rows;
        end

        % Button pushed function: createnewROIsetButton
        function createnewROIsetButtonPushed(app, event)
            if ~isempty(app.ImageFilenameDropDown_2.Value)
                app.curImageNum = find(strcmp(app.ImageFilenameDropDown_2.Items,app.ImageFilenameDropDown_2.Value),1);
                app.numRoiSets = app.numRoiSets + 1;
                RoiSetName = ['ROI set ' num2str(app.numRoiSets)];
                answer = inputdlg('Enter name for ROI set','ROI set name',[1 60],{RoiSetName});
                if ~isempty(answer{1})
                    RoiSetName = answer{1};
                end                
                app.numRois(app.numRoiSets) = 0;
                app.roiSets{app.numRoiSets,1} = {};
                app.roiSets{app.numRoiSets,2} = [];
                app.roiSets{app.numRoiSets,3} = [];
                app.roiSets{app.numRoiSets,4} = [app.imageWidths(app.curImageNum) app.imageWidths(app.curImageNum)];
                app.roiSets{app.numRoiSets,5} = []; %space for masks
                app.roiSets{app.numRoiSets,6} = []; %space for neuMasks
                app.roiSets{app.numRoiSets,7} = []; %space for neuRoiSetNames
                app.roiSetFilenames{app.numRoiSets} =  [RoiSetName '.mat'];
                app.roiSetFilepaths{app.numRoiSets} = app.saveDirectory;
                app.roiSetNames{app.numRoiSets} = RoiSetName;
                app.originImages{app.numRoiSets} = fullfile(app.imageFilepaths{app.curImageNum},app.imageFilenames{app.curImageNum});
                app.roiMethod{app.numRoiSets} = 'manual';
                app.updateLists();
                app.ROIsetnameDropDown.Value = RoiSetName;
            else
                msgbox('An image filename must first be selected to create a new ROI set')
            end
        end

        % Button pushed function: BeginmanualeditButton
        function BeginmanualeditButtonPushed(app, event)
            %get data for current image, roi set
            app.curImageNum = find(strcmp(app.ImageFilenameDropDown_2.Items,app.ImageFilenameDropDown_2.Value),1);
            app.curRoiSetNum = find(strcmp(app.ROIsetnameDropDown.Items,app.ROIsetnameDropDown.Value),1);
            app.selectedManualFrame = 1;
            app.selectedManualRoi = 1;
            app.ofroisonoffButton.Value = 1;
            app.manualRois.vertices = app.roiSets{app.curRoiSetNum,1};
            app.manualRois.center = app.roiSets{app.curRoiSetNum,2};
            app.manualRois.parameters = app.roiSets{app.curRoiSetNum,3};
            cla(app.UIAxes_2);
            hold(app.UIAxes_2,'on')
            app.mlineHandles = [];
            app.plHandle = [];
            app.meanImageHandle = [];
            app.maxImageHandle = [];
            
            %create images
            meanImage = mean(double(app.images{app.curImageNum}),3);
            meanImage = meanImage/prctile(meanImage,99.5,'all');
            maxImage = max(double(app.images{app.curImageNum}),[],3);
            maxImage = maxImage/prctile(maxImage,99.5,'all');
            app.meanImageHandle = imshow(meanImage,'Parent',app.UIAxes_2);
            app.maxImageHandle = imshow(maxImage,'Parent',app.UIAxes_2);
            
            %set axis size/limits
            app.UIAxes_2.Position = [558,165,550,550];
            app.UIAxes_2.Title.String = 'mean';
            app.UIAxes_2.XLim = [0 app.imageWidths(app.curImageNum)];
            app.UIAxes_2.YLim = [0 app.imageHeights(app.curImageNum)];
            
            %draw vertices for each ROI
            numEllipses = size(app.manualRois.center,1);
            if numEllipses>0
                for r = 1:numEllipses
                    vertices = app.manualRois.vertices{r};
                    line(app.UIAxes_2,vertices(:,1),vertices(:,2),'Color',[0 1 0]);
                    if r==1 %set #1 as editable
                        app.plHandle = drawpolyline(app.UIAxes_2,'Position',vertices,'Color',[0.5 1 0.5],'MarkerSize',2);
                    end
                end
                
                %get all line handles
                app.mlineHandles = findobj(app.UIAxes_2,'Type','line');
                app.mlineHandles = app.mlineHandles(numEllipses:-1:1); %reverse order to match ROI numbers
                
                %add ROIs to editROI dropdown
                roiCells = cell(numEllipses,1);
                for i = 1:numEllipses
                    roiCells{i} = num2str(i);
                end
                app.selectedROIDropDown.Items = roiCells;
                app.selectedROIDropDown.Value = num2str(1);
                                
                %update all visibilities
                app.updateManualFrame();
            end
            
        end

        % Value changed function: selectedROIDropDown
        function selectedROIDropDownValueChanged(app, event)
            value = app.selectedROIDropDown.Value;
            if ~isempty(value)
                app.getManualLine();
                app.selectedManualRoi = str2double(value);
                app.plHandle.Position = app.manualRois.vertices{app.selectedManualRoi};
            end
        end

        % Button pushed function: ddeleteROIButton
        function ddeleteROIButtonPushed(app, event)
            roi = app.selectedManualRoi;
            if ~isempty(roi)
                delete(app.mlineHandles(roi));
                app.mlineHandles(roi) = [];
                
                app.manualRois.vertices(roi) = [];
                app.manualRois.center(roi,:) = [];
                app.manualRois.parameters(roi,:) = [];
                
                numEllipses = size(app.manualRois.center,1);
                roiCells = cell(numEllipses,1);
                for i = 1:numEllipses
                    roiCells{i} = num2str(i);
                end
                app.selectedROIDropDown.Items = roiCells;
                if numEllipses==0
                    app.selectedManualRoi = {};
                    app.selectedROIDropDown.Value = {};
                    delete(app.plHandle);
                else
                    app.selectedManualRoi = max([1 roi-1]);
                    app.selectedROIDropDown.Value = num2str(app.selectedManualRoi);
                end
                
                if ~isempty(app.mlineHandles)
                    app.plHandle.Position = app.manualRois.vertices{app.selectedManualRoi};
                else
                end
            end
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            if size(app.manualRois.center,1)>0
                numEllipses = size(app.manualRois.center,1);
                app.numRois(app.curRoiSetNum) = numEllipses;
                
                app.roiSets{app.curRoiSetNum,1} = app.manualRois.vertices;
                app.roiSets{app.curRoiSetNum,2} = app.manualRois.center;
                app.roiSets{app.curRoiSetNum,3} = app.manualRois.parameters; 
                if strcmp(app.roiMethod{app.curRoiSetNum},'auto')
                    app.roiMethod{app.curRoiSetNum} = 'auto/manual';
                end
                app.updateLists();
            end
        end

        % Value changed function: ofroisonoffButton
        function ofroisonoffButtonValueChanged(app, event)
            value = app.ofroisonoffButton.Value;
            vis = {'off','on'};
            for r = 1:length(app.plHandles)
                app.mlineHandles(r).Visible = vis{value+1};                
            end
        end

        % Button pushed function: aaddROIButton
        function aaddROIButtonPushed(app, event)
            app.getManualLine();
            delete(app.plHandle);
            app.UIAxes_2.Title.String = 'click to draw ROI vertices (double-click to finish)';
            app.plHandle = drawpolyline(app.UIAxes_2,'Color',[0.5 1 0.5]);
            vertices = app.plHandle.Position;
            newLineHandle = line(app.UIAxes_2,vertices(:,1),vertices(:,2),'Color',[0 1 0]);
            
            numEllipses = size(app.manualRois.center,1)+1;
            if numEllipses==1
                app.mlineHandles = newLineHandle;
            else
                app.mlineHandles(numEllipses) = newLineHandle;
            end
            app.manualRois.vertices(numEllipses) = {nan};
            app.manualRois.center(numEllipses,:) = [nan nan];
            app.manualRois.parameters(numEllipses,:) = [nan nan nan];
            app.selectedManualRoi = numEllipses;     
            
            roiCells = cell(numEllipses,1);
                for i = 1:numEllipses
                    roiCells{i} = num2str(i);
                end
            app.selectedROIDropDown.Items = roiCells;
            app.selectedROIDropDown.Value = num2str(app.selectedManualRoi);
                
            app.updateManualFrame();
            app.getManualLine();
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            clickPosition = get(app.UIAxes_2, 'CurrentPoint');
            x = clickPosition(1,1);
            y = clickPosition(1,2);
            if ~isempty(app.manualRois.center)
                dx = app.manualRois.center(:,1)-x;
                dy = app.manualRois.center(:,1)-y;
                dist = sqrt(dx.^2 + dy.^2);
                [closestDist, closestROI] = min(dist);
                if closestDist<20
                    app.mlineHandles(app.selectedManualRoi).Color = [0 1 0];
                    app.getManualLine();
                    app.mlineHandles(closestROI).Color = [0.5 1 0.5];
                    app.selectedManualRoi = closestROI;
                else
                    app.mlineHandles(app.selectedManualRoi).Color = [0 1 0];
                    app.getManualLine();
                    app.mlineHandles(1).Color = [0.5 1 0.5];
                    app.selectedManualRoi = 1;
                end
            end
        end

        % Button pushed function: meanButton
        function meanButtonPushed(app, event)
            app.selectedManualFrame = 1;
            app.updateManualFrame();
        end

        % Button pushed function: maxButton
        function maxButtonPushed(app, event)
            app.selectedManualFrame = 2;
            app.updateManualFrame();
        end

        % Cell selection callback: UITable_RoiSetMask
        function UITable_RoiSetMaskCellSelection(app, event)
            rows = unique(event.Indices(:,1));
            app.selectedRoiSetMasks = rows;
            data = app.roiSetNames(rows);
            set(app.UITable2,'Data',data');
        end

        % Button pushed function: createmasksButton
        function createmasksButtonPushed(app, event)
            %check that all image sizes match
            imageSize = [];
            for s = 1:length(app.selectedRoiSetMasks)
                roi = app.selectedRoiSetMasks(s);
                imageSize = [imageSize; app.roiSets{roi,4}];
            end
            assert(all(imageSize(1,1)==imageSize(:,1)),'all ROI sets must be made from images of the same size');
            assert(all(imageSize(1,2)==imageSize(:,2)),'all ROI sets must be made from images of the same size');
            imageSize = imageSize(1,:);
            
            %create starting neuropil mask
            neuMask = true(imageSize(1),imageSize(2));
            sigma = app.roineuropilspacingEditField.Value/2; %amount of blur to get space around each ROI
            
            %loop for all roi sets to create ROI masks and neu Mask
            waitbarHandle = waitbar(0,'Creating ROI masks');
            nm = length(app.selectedRoiSetMasks);
            for m = 1:nm
                waitbar(m/(nm*3),waitbarHandle);
                roi = app.selectedRoiSetMasks(m);
                vertices = app.roiSets{roi,1};
                nR = app.numRois(roi);
                roiMasks = false(imageSize(1),imageSize(2),nR);
                for r = 1:nR
                    roiVertices = vertices{r};
                    %calculate mask
                    curMask = poly2mask(roiVertices(:,1),roiVertices(:,2),imageSize(1),imageSize(2));
                    roiMasks(:,:,r) = curMask;
                                        
                    %blur for bigger mask to remove roi from neuMask
                    neuMask = neuMask & ~(imgaussfilt(double(curMask),sigma)>0);
                end
                
                app.roiSets{roi,5} = roiMasks;
            end
            
            %loop for all roi sets again to create neu Masks
            minNeuPixels = app.minneuropilpixelsEditField.Value;
            for m = 1:nm
                waitbar((nm+m)/(nm*3),waitbarHandle);
                roi = app.selectedRoiSetMasks(m);
                vertices = app.roiSets{roi,1};
                nR = app.numRois(roi);
                neuMasks = false(imageSize(1),imageSize(2),nR);
                for r = 1:nR
                    roiVertices = vertices{r};
                    %calculate mask
                    curMask = poly2mask(roiVertices(:,1),roiVertices(:,2),imageSize(1),imageSize(2));
                    numNeuPixels = 0;
                    sigmaAdd = 0;
                    
                    while numNeuPixels<minNeuPixels
                        %blur for biggest mask to remove roi from neuMask
                        bigMask = imgaussfilt(double(curMask),(sigma*2)+sigmaAdd)>0;
                        curNeuMask = bigMask & neuMask;
                        numNeuPixels = sum(curNeuMask,'all');
                        sigmaAdd = sigmaAdd+0.5;
                    end
                    
                    neuMasks(:,:,r) = curNeuMask;
                end
                
                app.roiSets{roi,6} = neuMasks;
            end
            
            %create string of all ROI set names used to create neu masks
            neuRoiSetNames = [];
            for m = 1:nm
                roi = app.selectedRoiSetMasks(m);
                if s==1
                    neuRoiSetNames = app.roiSetNames{roi};
                else
                    neuRoiSetNames = [neuRoiSetNames ',' app.roiSetNames{roi}];
                end
            end
            for m = 1:nm
                roi = app.selectedRoiSetMasks(m);
                app.roiSets{roi,7} = neuRoiSetNames;
            end
            cla(app.UIAxes_3)
            hold(app.UIAxes_3,'on');
            
            %create an image of all masks (neu in grey)
            cla(app.UIAxes_3)
            hold(app.UIAxes_3,'on');
            colorRoiMask = zeros([size(neuMask) 3]);
            colorNeuMask = zeros([size(neuMask) 3]);
            for m = 1:nm
                waitbar((nm+nm+m)/(nm*3),waitbarHandle);
                roiColor = app.UITable_RoiSetMask.Data(roi,2:4);
                roiColor = permute(cell2mat(roiColor),[1 3 2]);
                roiColor = repmat(roiColor,[size(neuMask) 1]);
                roi = app.selectedRoiSetMasks(m);
                mask = app.roiSets{roi,5};
                mask = any(mask,3);
                mask = repmat(mask,[1 1 3]);
                mask = mask.*roiColor;
                colorRoiMask = colorRoiMask + mask;
                
                neuColor = roiColor;
                neuColor(:) = 0.5;
                mask = app.roiSets{roi,6};
                mask = any(mask,3);
                mask = repmat(mask,[1 1 3]);
                mask = mask.*neuColor;
                colorNeuMask = colorNeuMask + mask;
            end
            close(waitbarHandle);
            colorNeuMask(colorNeuMask>0) = 0.5;
            imshow(colorRoiMask + colorNeuMask,'Parent',app.UIAxes_3);
            
            %update lists for masks created
            app.updateLists();
        end

        % Button pushed function: SaveallROIsetsButton
        function SaveallROIsetsButtonPushed(app, event)
            if isempty(app.saveDirectory)
                saveDir = uigetdir('Select save directory');
                assert(~isempty(saveDir),'No save directory selected');
                app.saveDirectory = saveDir;
                app.NosavedirectoryLabel.Text = app.saveDirectory;
            end
            for r = 1:app.numRoiSets
                roiSet = app.roiSets(r,:);
                save(fullfile(app.roiSetFilepaths{r},app.roiSetFilenames{r}),'roiSet');
                app.roiSetFilepaths{r} = app.saveDirectory;
            end
            app.updateLists();
        end

        % Button pushed function: CalculatetracesButton
        function CalculatetracesButtonPushed(app, event)
            app.curImageNum = find(strcmp(app.ImageFilenameDropDown_3.Items,app.ImageFilenameDropDown_3.Value),1);
            app.curRoiSetNum = find(strcmp(app.ROIsetnameDropDown_2.Items,app.ROIsetnameDropDown_2.Value),1);
            app.numTraces = app.numTraces+1;
            
            curImage = app.images{app.curImageNum};
            curRoiMasks = app.roiSets{app.curRoiSetNum,5};
            curNeuMasks = app.roiSets{app.curRoiSetNum,6};
            nf = app.numFrames(app.curImageNum);
            nr = app.numRois(app.curRoiSetNum);
            Fraw = nan([nf nr]);
            Fneu = nan([nf nr]);
            waitbarHandle = waitbar(0,'Calculating fluorescence traces');
            for r = 1:nr
                waitbar(r/nr,waitbarHandle);
                
                curMask = curRoiMasks(:,:,r);
                nPix = sum(curMask,'all');
                tmp = curImage(repmat(curMask,[1 1 nf]));
                tmp = reshape(tmp,[nPix nf]);
                Fraw(:,r) = mean(tmp);
                
                curMask = curNeuMasks(:,:,r);
                nPix = sum(curMask,'all');
                tmp = curImage(repmat(curMask,[1 1 nf]));
                tmp = reshape(tmp,[nPix nf]);
                Fneu(:,r) = mean(tmp);
            end
            close(waitbarHandle)
            F = Fraw-(0.7*Fneu);
            
            %create figure
            cla(app.UIAxes_4)
            hold(app.UIAxes_4,'on');
            
            %scale and offset 1st trace
            app.fHandle = plot(app.UIAxes_4,F(:,1),'k','LineWidth',1); 
            xlim(app.UIAxes_4,[1 nf]);
            app.frawHandle = plot(app.UIAxes_4,Fraw(:,1),'b');
            app.fneuHandle = plot(app.UIAxes_4,Fneu(:,1),'r');
            legend(app.UIAxes_4,{'Fraw-0.7*Fneu','Fraw','Fneu'});
                    
            %store the data
            app.traces(app.numTraces).F = F;
            app.traces(app.numTraces).Fraw = Fraw;
            app.traces(app.numTraces).Fneu = Fneu;
            app.traces(app.numTraces).numFrames = nf;
            app.traces(app.numTraces).numRois = nr;
            app.traces(app.numTraces).ImageFilename = app.ImageFilenameDropDown_3.Value;
            app.traces(app.numTraces).RoiSetname = app.ROIsetnameDropDown_2.Value;
            app.tracesFilenames(app.numTraces) = {['Traces_' app.ROIsetnameDropDown_2.Value '_' app.ImageFilenameDropDown_3.Value '.mat']};
            app.tracesFilepaths{app.numTraces} = '';
            
            app.selectedTraceRoi = 1;
            app.curTraceNum = app.numTraces;
            app.updateTraceFrame();
            
            app.updateLists();
        end

        % Button pushed function: SavetracesButton
        function SavetracesButtonPushed(app, event)
            if isempty(app.saveDirectory)
                saveDir = uigetdir('Select save directory');
                assert(~isempty(saveDir),'No save directory selected');
                app.saveDirectory = saveDir;
                app.NosavedirectoryLabel.Text = app.saveDirectory;
            end
            
            for i = 1:app.numTraces
                Traces = app.traces(i);
                app.tracesFilepaths(i) = {app.saveDirectory};
                save(fullfile(app.tracesFilepaths{i},app.tracesFilenames{i}),'Traces');
            end
            
            app.SaveallROIsetsButtonPushed();
            
            app.updateLists();
        end

        % Button pushed function: RemoveselectedtraceButton
        function RemoveselectedtraceButtonPushed(app, event)
            if ~isempty(app.selectedTraceRows) && all(app.selectedTraceRows<=app.numTraces)
                app.numTraces = app.numTraces-length(app.selectedTraceRows);
                app.curTraceNum = max([1 app.curTraceNum-1]);
                app.traces(app.selectedTraceRows) = [];
                app.tracesFilenames(app.selectedTraceRows) = [];
                app.tracesFilepaths(app.selectedTraceRows) = [];
                app.updateLists();
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            rows = unique(event.Indices(:,1));
            app.selectedTraceRows = rows;
        end

        % Value changed function: ROIDropDown
        function ROIDropDownValueChanged(app, event)
            roi = str2double(app.ROIDropDown.Value);
            app.selectedTraceRoi = roi;
            app.updateTraceFrame();
        end

        % Value changed function: showrawneuCheckBox
        function showrawneuCheckBoxValueChanged(app, event)
            value = app.showrawneuCheckBox.Value;
            app.showTraceRaw = value;
            app.updateTraceFrame();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManageFilesTab = uitab(app.TabGroup);
            app.ManageFilesTab.Title = 'Manage Files';
            app.UITable_Image = uitable(app.ManageFilesTab);
            app.UITable_Image.ColumnName = {'Filepath'; 'Filename'; 'ImageHeight'; 'ImageWidth'; 'NumFrames'};
            app.UITable_Image.RowName = {};
            app.UITable_Image.CellSelectionCallback = createCallbackFcn(app, @UITable_ImageCellSelection, true);
            app.UITable_RoiSet = uitable(app.ManageFilesTab);
            app.UITable_RoiSet.ColumnName = {'Filepath'; 'Filename'; 'RoiSetName'; 'OriginImage'; 'NumRois'; 'Method'; 'MasksCreated'; 'NeuropilRoiSets'};
            app.UITable_RoiSet.RowName = {};
            app.UITable_RoiSet.CellSelectionCallback = createCallbackFcn(app, @UITable_RoiSetCellSelection, true);
            app.LoadimageimageseriesButton = uibutton(app.ManageFilesTab, 'push');
            app.LoadimageimageseriesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageimageseriesButtonPushed, true);
            app.LoadimageimageseriesButton.Text = 'Load image/image series';
            app.LoadROIsetButton = uibutton(app.ManageFilesTab, 'push');
            app.LoadROIsetButton.Enable = 'off';
            app.LoadROIsetButton.Text = 'Load ROI set';
            app.RemoveselectedimageButton = uibutton(app.ManageFilesTab, 'push');
            app.RemoveselectedimageButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedimageButtonPushed, true);
            app.RemoveselectedimageButton.Text = 'Remove selected image';
            app.RemoveselectedROIsetButton = uibutton(app.ManageFilesTab, 'push');
            app.RemoveselectedROIsetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedROIsetButtonPushed, true);
            app.RemoveselectedROIsetButton.Text = 'Remove selected ROI set';
            app.NosavedirectoryLabel = uilabel(app.ManageFilesTab);
            app.NosavedirectoryLabel.Text = 'No save directory';
            app.SetsavedirectoryButton = uibutton(app.ManageFilesTab, 'push');
            app.SetsavedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetsavedirectoryButtonPushed, true);
            app.SetsavedirectoryButton.Text = 'Set save directory';
            app.AutoROIsTab = uitab(app.TabGroup);
            app.AutoROIsTab.Title = 'Auto ROIs';
            app.UIAxes = uiaxes(app.AutoROIsTab);
            app.ImageFilenameDropDownLabel = uilabel(app.AutoROIsTab);
            app.ImageFilenameDropDownLabel.Text = 'Image Filename';
            app.ImageFilenameDropDown = uidropdown(app.AutoROIsTab);
            app.ImageFilenameDropDown.Items = {};
            app.ImageFilenameDropDown.Value = {};
            app.UITable_RoiSet_2 = uitable(app.AutoROIsTab);
            app.UITable_RoiSet_2.ColumnName = {'Filepath'; 'Filename'; 'RoiSetName'; 'OriginImage'; 'NumRois'; 'Method'; 'MasksCreated'; 'NeuropilRoiSets'};
            app.UITable_RoiSet_2.RowName = {};
            app.RefineROIsetPanel = uipanel(app.AutoROIsTab);
            app.RefineROIsetPanel.Title = 'Refine ROI set';
            app.InclusionCriteriaLabel = uilabel(app.RefineROIsetPanel);
            app.InclusionCriteriaLabel.Text = 'Inclusion Criteria:';
            app.minEditFieldLabel = uilabel(app.RefineROIsetPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.RefineROIsetPanel, 'numeric');
            app.minEditField.Limits = [0 Inf];
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditField.Value = 7;
            app.maxEditFieldLabel = uilabel(app.RefineROIsetPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.RefineROIsetPanel, 'numeric');
            app.maxEditField.Limits = [0 Inf];
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.maxEditField.Value = 15;
            app.ParameterLabel = uilabel(app.RefineROIsetPanel);
            app.ParameterLabel.Text = 'Parameter';
            app.diameterLabel = uilabel(app.RefineROIsetPanel);
            app.diameterLabel.Text = '5: diameter';
            app.SaveROIsetPanel = uipanel(app.AutoROIsTab);
            app.SaveROIsetPanel.Title = 'Save ROI set';
            app.storeROIsetButton = uibutton(app.SaveROIsetPanel, 'push');
            app.storeROIsetButton.ButtonPushedFcn = createCallbackFcn(app, @storeROIsetButtonPushed, true);
            app.storeROIsetButton.Text = 'store ROI set';
            app.ROIsetnameEditFieldLabel = uilabel(app.SaveROIsetPanel);
            app.ROIsetnameEditFieldLabel.Text = 'ROI set name';
            app.ROIsetnameEditField = uieditfield(app.SaveROIsetPanel, 'text');
            app.framenumbersPanel = uipanel(app.AutoROIsTab);
            app.framenumbersPanel.Title = 'frame numbers';
            app.meanButton_2 = uibutton(app.framenumbersPanel, 'push');
            app.meanButton_2.ButtonPushedFcn = createCallbackFcn(app, @meanButton_2Pushed, true);
            app.meanButton_2.Text = '2. mean';
            app.ROIsButton_2 = uibutton(app.framenumbersPanel, 'push');
            app.ROIsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ROIsButton_2Pushed, true);
            app.ROIsButton_2.Text = '3. ROIs';
            app.ROIsButton_3 = uibutton(app.framenumbersPanel, 'push');
            app.ROIsButton_3.ButtonPushedFcn = createCallbackFcn(app, @ROIsButton_3Pushed, true);
            app.ROIsButton_3.Text = '4. ROI #s';
            app.diameterButton = uibutton(app.framenumbersPanel, 'push');
            app.diameterButton.ButtonPushedFcn = createCallbackFcn(app, @diameterButtonPushed, true);
            app.diameterButton.Text = '5. diameter';
            app.brightnessButton = uibutton(app.framenumbersPanel, 'push');
            app.brightnessButton.ButtonPushedFcn = createCallbackFcn(app, @brightnessButtonPushed, true);
            app.brightnessButton.Text = '7. brightness';
            app.circularityButton = uibutton(app.framenumbersPanel, 'push');
            app.circularityButton.ButtonPushedFcn = createCallbackFcn(app, @circularityButtonPushed, true);
            app.circularityButton.Text = '6. circularity';
            app.enclosedButton = uibutton(app.framenumbersPanel, 'push');
            app.enclosedButton.ButtonPushedFcn = createCallbackFcn(app, @enclosedButtonPushed, true);
            app.enclosedButton.Text = '9. enclosed';
            app.edgeButton = uibutton(app.framenumbersPanel, 'push');
            app.edgeButton.ButtonPushedFcn = createCallbackFcn(app, @edgeButtonPushed, true);
            app.edgeButton.Text = '8. edge';
            app.uncertaintyButton = uibutton(app.framenumbersPanel, 'push');
            app.uncertaintyButton.ButtonPushedFcn = createCallbackFcn(app, @uncertaintyButtonPushed, true);
            app.uncertaintyButton.Text = '10. uncertainty';
            app.includedButton = uibutton(app.framenumbersPanel, 'push');
            app.includedButton.ButtonPushedFcn = createCallbackFcn(app, @includedButtonPushed, true);
            app.includedButton.Text = '1. included';
            app.keyboardshortcutctrlnumLabel = uilabel(app.framenumbersPanel);
            app.keyboardshortcutctrlnumLabel.Text = {'keyboard shortcut:'; 'ctrl + num'};
            app.autodetectROIsButton = uibutton(app.AutoROIsTab, 'push');
            app.autodetectROIsButton.ButtonPushedFcn = createCallbackFcn(app, @autodetectROIsButtonPushed, true);
            app.autodetectROIsButton.Text = 'auto detect ROIs';
            app.ManualROIsTab = uitab(app.TabGroup);
            app.ManualROIsTab.Title = 'Manual ROIs';
            app.UIAxes_2 = uiaxes(app.ManualROIsTab);
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.ImageFilenameDropDown_2Label = uilabel(app.ManualROIsTab);
            app.ImageFilenameDropDown_2Label.Text = 'Image Filename';
            app.ImageFilenameDropDown_2 = uidropdown(app.ManualROIsTab);
            app.ImageFilenameDropDown_2.Items = {};
            app.ImageFilenameDropDown_2.Value = {};
            app.ROIsetnameDropDownLabel = uilabel(app.ManualROIsTab);
            app.ROIsetnameDropDownLabel.Text = 'ROI set name';
            app.ROIsetnameDropDown = uidropdown(app.ManualROIsTab);
            app.ROIsetnameDropDown.Items = {};
            app.ROIsetnameDropDown.Value = {};
            app.UITable_RoiSet_3 = uitable(app.ManualROIsTab);
            app.UITable_RoiSet_3.ColumnName = {'Filepath'; 'Filename'; 'RoiSetName'; 'OriginImage'; 'NumRois'; 'Method'; 'MasksCreated'; 'NeuropilRoiSets'};
            app.UITable_RoiSet_3.RowName = {};
            app.EditROIPanel = uipanel(app.ManualROIsTab);
            app.EditROIPanel.Title = 'Edit ROI';
            app.selectedROIDropDownLabel = uilabel(app.EditROIPanel);
            app.selectedROIDropDownLabel.Text = 'selected ROI';
            app.selectedROIDropDown = uidropdown(app.EditROIPanel);
            app.selectedROIDropDown.Items = {};
            app.selectedROIDropDown.ValueChangedFcn = createCallbackFcn(app, @selectedROIDropDownValueChanged, true);
            app.selectedROIDropDown.Value = {};
            app.ddeleteROIButton = uibutton(app.EditROIPanel, 'push');
            app.ddeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @ddeleteROIButtonPushed, true);
            app.ddeleteROIButton.Text = '(d) delete ROI';
            app.aaddROIButton = uibutton(app.EditROIPanel, 'push');
            app.aaddROIButton.ButtonPushedFcn = createCallbackFcn(app, @aaddROIButtonPushed, true);
            app.aaddROIButton.Text = '(a) add ROI';
            app.frameshortcutsPanel = uipanel(app.ManualROIsTab);
            app.frameshortcutsPanel.Title = 'frame shortcuts';
            app.meanButton = uibutton(app.frameshortcutsPanel, 'push');
            app.meanButton.ButtonPushedFcn = createCallbackFcn(app, @meanButtonPushed, true);
            app.meanButton.Text = '1. mean';
            app.maxButton = uibutton(app.frameshortcutsPanel, 'push');
            app.maxButton.ButtonPushedFcn = createCallbackFcn(app, @maxButtonPushed, true);
            app.maxButton.Text = '2. max';
            app.ofroisonoffButton = uibutton(app.frameshortcutsPanel, 'state');
            app.ofroisonoffButton.ValueChangedFcn = createCallbackFcn(app, @ofroisonoffButtonValueChanged, true);
            app.ofroisonoffButton.Text = '(o/f) rois on/off';
            app.keyboardshortcutctrlnumLabel_2 = uilabel(app.frameshortcutsPanel);
            app.keyboardshortcutctrlnumLabel_2.Text = {'keyboard shortcut:'; 'ctrl + num'};
            app.BeginmanualeditButton = uibutton(app.ManualROIsTab, 'push');
            app.BeginmanualeditButton.ButtonPushedFcn = createCallbackFcn(app, @BeginmanualeditButtonPushed, true);
            app.BeginmanualeditButton.Text = 'Begin manual edit';
            app.createnewROIsetButton = uibutton(app.ManualROIsTab, 'push');
            app.createnewROIsetButton.ButtonPushedFcn = createCallbackFcn(app, @createnewROIsetButtonPushed, true);
            app.createnewROIsetButton.Text = 'create new ROI set';
            app.SavechangesButton = uibutton(app.ManualROIsTab, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.CompareSetsTab = uitab(app.TabGroup);
            app.CompareSetsTab.Title = 'Compare Sets';
            app.UIAxes_5 = uiaxes(app.CompareSetsTab);
            app.UITable_RoiSet_6 = uitable(app.CompareSetsTab);
            app.UITable_RoiSet_6.ColumnName = {'Filepath'; 'Filename'; 'RoiSetName'; 'OriginImage'; 'NumRois'; 'Method'; 'MasksCreated'; 'NeuropilRoiSets'};
            app.UITable_RoiSet_6.RowName = {};
            app.SelectROIsetsPanel_2 = uipanel(app.CompareSetsTab);
            app.SelectROIsetsPanel_2.Title = 'Select ROI sets';
            app.UITable_RoiSetMask_2 = uitable(app.SelectROIsetsPanel_2);
            app.UITable_RoiSetMask_2.ColumnName = {'ROI set'; 'R'; 'G'; 'B'};
            app.UITable_RoiSetMask_2.RowName = {};
            app.UITable_RoiSetMask_2.ColumnEditable = [false true true true];
            app.UITable2_2 = uitable(app.SelectROIsetsPanel_2);
            app.UITable2_2.ColumnName = {'Selected ROI sets'};
            app.UITable2_2.RowName = {};
            app.CreateMasksTab = uitab(app.TabGroup);
            app.CreateMasksTab.Title = 'Create Masks';
            app.UIAxes_3 = uiaxes(app.CreateMasksTab);
            app.UITable_RoiSet_4 = uitable(app.CreateMasksTab);
            app.UITable_RoiSet_4.ColumnName = {'Filepath'; 'Filename'; 'RoiSetName'; 'OriginImage'; 'NumRois'; 'Method'; 'MasksCreated'; 'NeuropilRoiSets'};
            app.UITable_RoiSet_4.RowName = {};
            app.SelectROIsetsPanel = uipanel(app.CreateMasksTab);
            app.SelectROIsetsPanel.Title = 'Select ROI sets';
            app.UITable_RoiSetMask = uitable(app.SelectROIsetsPanel);
            app.UITable_RoiSetMask.ColumnName = {'ROI set'; 'R'; 'G'; 'B'};
            app.UITable_RoiSetMask.RowName = {};
            app.UITable_RoiSetMask.ColumnEditable = [false true true true];
            app.UITable_RoiSetMask.CellSelectionCallback = createCallbackFcn(app, @UITable_RoiSetMaskCellSelection, true);
            app.UITable2 = uitable(app.SelectROIsetsPanel);
            app.UITable2.ColumnName = {'Selected ROI sets'};
            app.UITable2.RowName = {};
            app.CreatemasksPanel = uipanel(app.CreateMasksTab);
            app.CreatemasksPanel.Title = 'Create masks';
            app.minneuropilpixelsEditFieldLabel = uilabel(app.CreatemasksPanel);
            app.minneuropilpixelsEditFieldLabel.Text = 'min neuropil pixels';
            app.minneuropilpixelsEditField = uieditfield(app.CreatemasksPanel, 'numeric');
            app.minneuropilpixelsEditField.Limits = [10 1000];
            app.minneuropilpixelsEditField.Value = 50;
            app.roineuropilspacingEditFieldLabel = uilabel(app.CreatemasksPanel);
            app.roineuropilspacingEditFieldLabel.Text = 'roi/neuropil spacing';
            app.roineuropilspacingEditField = uieditfield(app.CreatemasksPanel, 'numeric');
            app.roineuropilspacingEditField.Value = 5;
            app.createmasksButton = uibutton(app.CreatemasksPanel, 'push');
            app.createmasksButton.ButtonPushedFcn = createCallbackFcn(app, @createmasksButtonPushed, true);
            app.createmasksButton.Text = 'create masks';
            app.ProcessandSaveTab = uitab(app.TabGroup);
            app.ProcessandSaveTab.Title = 'Process and Save';
            app.UIAxes_4 = uiaxes(app.ProcessandSaveTab);
            app.ImageFilenameDropDown_3Label = uilabel(app.ProcessandSaveTab);
            app.ImageFilenameDropDown_3Label.Text = 'Image Filename';
            app.ImageFilenameDropDown_3 = uidropdown(app.ProcessandSaveTab);
            app.ImageFilenameDropDown_3.Items = {};
            app.ImageFilenameDropDown_3.Value = {};
            app.ROIsetnameDropDown_2Label = uilabel(app.ProcessandSaveTab);
            app.ROIsetnameDropDown_2Label.Text = 'ROI set name';
            app.ROIsetnameDropDown_2 = uidropdown(app.ProcessandSaveTab);
            app.ROIsetnameDropDown_2.Items = {};
            app.ROIsetnameDropDown_2.Value = {};
            app.UITable_RoiSet_5 = uitable(app.ProcessandSaveTab);
            app.UITable_RoiSet_5.ColumnName = {'Filepath'; 'Filename'; 'RoiSetName'; 'OriginImage'; 'NumRois'; 'Method'; 'MasksCreated'; 'NeuropilRoiSets'};
            app.UITable_RoiSet_5.RowName = {};
            app.SaveallROIsetsButton = uibutton(app.ProcessandSaveTab, 'push');
            app.SaveallROIsetsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallROIsetsButtonPushed, true);
            app.SaveallROIsetsButton.Text = 'Save all ROI sets';
            app.GeneratetracesPanel = uipanel(app.ProcessandSaveTab);
            app.GeneratetracesPanel.Title = 'Generate traces';
            app.CalculatetracesButton = uibutton(app.GeneratetracesPanel, 'push');
            app.CalculatetracesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatetracesButtonPushed, true);
            app.CalculatetracesButton.Text = 'Calculate traces';
            app.SavetracesButton = uibutton(app.GeneratetracesPanel, 'push');
            app.SavetracesButton.ButtonPushedFcn = createCallbackFcn(app, @SavetracesButtonPushed, true);
            app.SavetracesButton.Text = 'Save traces';
            app.UITable = uitable(app.GeneratetracesPanel);
            app.UITable.ColumnName = {'Filepath'; 'Filename'; 'numFrames'; 'numRois'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.RemoveselectedtraceButton = uibutton(app.GeneratetracesPanel, 'push');
            app.RemoveselectedtraceButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedtraceButtonPushed, true);
            app.RemoveselectedtraceButton.Text = 'Remove selected trace';
            app.ROIDropDownLabel = uilabel(app.ProcessandSaveTab);
            app.ROIDropDownLabel.Text = 'ROI';
            app.ROIDropDown = uidropdown(app.ProcessandSaveTab);
            app.ROIDropDown.Items = {};
            app.ROIDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIDropDownValueChanged, true);
            app.ROIDropDown.Value = {};
            app.showrawneuCheckBox = uicheckbox(app.ProcessandSaveTab);
            app.showrawneuCheckBox.ValueChangedFcn = createCallbackFcn(app, @showrawneuCheckBoxValueChanged, true);
            app.showrawneuCheckBox.Text = 'show raw/neu';
            app.showrawneuCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROImaker

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end